classdef ModoA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        ModoTab                       matlab.ui.container.Tab
        MODOBButton                   matlab.ui.control.Button
        MODOAButton                   matlab.ui.control.Button
        ImportarTab                   matlab.ui.container.Tab
        PreprocesarButton             matlab.ui.control.Button
        ImportButton                  matlab.ui.control.Button
        UIAxes2                       matlab.ui.control.UIAxes
        HampelTab                     matlab.ui.container.Tab
        SiguienteButton               matlab.ui.control.Button
        UIAxes4                       matlab.ui.control.UIAxes
        ButterworthTab                matlab.ui.container.Tab
        SiguienteButton_3             matlab.ui.control.Button
        UIAxes5                       matlab.ui.control.UIAxes
        VentaneoTab                   matlab.ui.container.Tab
        NextButton                    matlab.ui.control.Button
        ExtraerCaracteristicasButton  matlab.ui.control.Button
        UIAxes6                       matlab.ui.control.UIAxes
        CaracteristicasTab            matlab.ui.container.Tab
        ClasificarButton              matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        ClasificacionTab              matlab.ui.container.Tab
        ClasificacinLabel             matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
    end

     properties (Access = private)
        inputData 
        PreprocessedData
        PreprocessedColumnName
        ButterworthData
        ButterFilteredData
        SegmentedData
        CurrentWindow
        NumWindows
        FeatureMatrix
        DatosSeg
        featureTable
        FeatureTable
     end 
    
   methods (Access = private)

function filtered = hampel_filter(app, data, window_size)
    % Hampel filter equivalente a la implementación Python.
    % - No omite valores NaN
    % - Misma definición de ventana: data[i - k : i + k + 1]
    % - Misma iteración de índices: for i = k : n - k - 1
    % - Mismo umbral: 1.4826 * MAD

    filtered = data;
    k = floor(window_size / 2);
    n = length(data);

    % En MATLAB los índices comienzan en 1, así que ajustamos el rango
    for i = (k + 1):(n - k)
        window = data(i - k : i + k); % mismo tamaño de ventana que en Python
        median_val = median(window);  % no se omiten NaN
        mad_val = median(abs(window - median_val));
        threshold = 1.4826 * mad_val;

        if abs(data(i) - median_val) > threshold
            filtered(i) = median_val;
                end
            end
    end
     
    
       function filtered = butter_lowpass_filter(app, data, cutoff, fs, order)
        % Aplica un filtro pasa bajas Butterworth a una señal
        nyquist = 0.5 * fs;
        normal_cutoff = cutoff / nyquist;
        [b, a] = butter(order, normal_cutoff, 'low');
        filtered = filtfilt(b, a, data);
    end

        
function segments = segment_data(app, data_table, num_windows)
    % Segmenta una tabla en num_windows subtablas
    % (la última incluye el resto de filas)
    %
    % Equivalente a la función Python:
    %   segment_dataframe(df, num_windows)
    %
    % Inputs:
    %   data_table  - tabla con las columnas de datos
    %   num_windows - número de segmentos a crear
    %
    % Output:
    %   segmented_tables - celda 1xN con subtablas segmentadas

    row_count = height(data_table);
    window_size = floor(row_count / num_windows);
    segments = cell(1, num_windows);

    for i = 1:num_windows
        start_idx = (i - 1) * window_size + 1;
        if i < num_windows
            end_idx = i * window_size;
        else
            end_idx = row_count; % última ventana con el resto
        end
        segments{i} = data_table(start_idx:end_idx,:);
    end
        end


    function features = extract_features_matlab(app, segment_table, target_columns)
    % Extrae características equivalentes a la implementación Python
    % Usa divisor N (no N-1), kurtosis tipo Fisher, y normalización de histograma tipo density=True

    all_features = [];

    for c = 1:numel(target_columns)
        column_name = target_columns{c};

        if ismember(column_name, segment_table.Properties.VariableNames)
            data = segment_table.(column_name);
            data = data(~isnan(data));
            N = numel(data);

            if N == 0
                feats = nan(1,9);
            else
                % --- Estadísticas básicas ---
                mean_val = mean(data);
                std_val = std(data, 1);  % divisor N (poblacional)
                var_val = var(data, 1);  % divisor N (poblacional)
                energy_val = sum(data.^2) / N;

                % --- Entropía estilo np.histogram(density=True) ---
                [counts, edges] = histcounts(data, 30);
                bin_widths = diff(edges);
                pdf = counts ./ (sum(counts .* bin_widths)); % normaliza área total = 1
                pdf = pdf(pdf > 0);
                entropy_val = -sum(pdf .* log2(pdf));

                % --- Skewness y kurtosis estilo SciPy (Fisher=True) ---
                skew_val = skewness(data);
                kurt_val = kurtosis(data) - 3; % MATLAB usa Pearson; restamos 3 para Fisher

                % --- MAV y cruces por cero ---
                mav_val = mean(abs(data));
                zero_crossings = sum(diff(sign(data)) ~= 0);

                feats = [mean_val, std_val, var_val, energy_val, entropy_val, ...
                         skew_val, kurt_val, mav_val, zero_crossings];
            end
        else
            feats = nan(1,9);
        end

        all_features = [all_features feats];
    end

    features = all_features;
    end
   end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
    % Diálogo para seleccionar el archivo CSV
    [archivo, ruta] = uigetfile('*.csv', 'Seleccionar archivo CSV');
    
    % Si el usuario cancela
    if isequal(archivo, 0)
        uialert(app.UIFigure, 'No se seleccionó ningún archivo', 'Aviso');
        return;
    end

    % Ruta completa
    ruta_completa = fullfile(ruta, archivo);

    try
        % Leer CSV
        datos = readtable(ruta_completa);
    
        % Mostrar nombres reales de las columnas leídas
        disp(datos.Properties.VariableNames);
    
        % Buscar la columna de aceleración Y de forma flexible
        nombreColumna = datos.Properties.VariableNames(contains(datos.Properties.VariableNames, 'AccelerationY', 'IgnoreCase', true));
    
        if isempty(nombreColumna)
            uialert(app.UIFigure, 'No se encontró una columna con "Acceleration Y".', 'Error');
            return;
        end
    
        % Guardar los datos en la app
        app.inputData = datos;
    
        % Extraer y graficar la columna Z
        zData = datos.(nombreColumna{1});
        plot(app.UIAxes2, zData, 'LineWidth', 1.5);
        grid(app.UIAxes2, 'on');
        title(app.UIAxes2, 'Aceleración Y(g)');
        xlabel(app.UIAxes2, 'Muestras');
        ylabel(app.UIAxes2, 'Aceleración (g)');
    
        % Mensaje de éxito
        uialert(app.UIFigure, sprintf('Archivo "%s" importado correctamente.', archivo), 'Éxito');
    
    catch err
        uialert(app.UIFigure, sprintf('Error al cargar el archivo:\n%s', err.message), 'Error');
    end
        end

        % Button pushed function: PreprocesarButton
        function PreprocesarButtonPushed(app, event)
% 1) Cambiar de pestaña
app.TabGroup.SelectedTab = app.HampelTab;

% 2) Verificar datos
if isempty(app.inputData)
    uialert(app.UIFigure, 'Primero importa un archivo CSV.', 'Error');
    return;
end

% 3) Mostrar nombres reales de columnas (opcional para depuración)
vars = app.inputData.Properties.VariableNames;
fprintf('Columnas detectadas:\n');
for i = 1:numel(vars)
    fprintf('  %d: %s\n', i, vars{i});
end

% 4) Buscar la columna de Acceleration Y de forma robusta
nombreColumna = app.inputData.Properties.VariableNames(contains(app.inputData.Properties.VariableNames, 'AccelerationY', 'IgnoreCase', true));

if isempty(nombreColumna)
    uialert(app.UIFigure, 'No se encontró una columna con "Acceleration Y".', 'Error');
    return;
end

% 5) Extraer columna y convertir a vector numérico seguro
col = app.inputData.(nombreColumna{1});

if istable(col)
    col = table2array(col);
elseif iscell(col)
    col = str2double(string(col));
elseif isstring(col) || ischar(col)
    col = str2double(col);
end

col = double(col(:)); % vector columna

if ~isnumeric(col) || isempty(col) || all(isnan(col))
    uialert(app.UIFigure, sprintf('La columna %s no contiene datos numéricos válidos.', nombreColumna{1}), 'Error');
    return;
end

% 6) Aplicar Hampel
window_size = 10; % ajusta si hace falta
if numel(col) < 3
    uialert(app.UIFigure, 'Señal demasiado corta para filtrar.', 'Error');
    return;
end

filtered = app.hampel_filter(col, window_size);

% 7) Graficar en la tab Preprocesar
app.TabGroup.SelectedTab = app.HampelTab;
ax = app.UIAxes4; % <-- asegúrate que este sea el nombre real del axes
cla(ax);
plot(ax, col, 'Color',[0 0.4470 0.7410], 'DisplayName', 'Original');
hold(ax, 'on');
plot(ax, filtered, 'r', 'LineWidth', 1.2, 'DisplayName', 'Hampel filtrado');
hold(ax, 'off');
grid(ax, 'on');
legend(ax, 'show');
title(ax, sprintf('Original vs Hampel', nombreColumna{1}), 'Interpreter', 'none');
xlabel(ax, 'Muestras');
ylabel(ax, 'Aceleración (g)');

% 8) Guardar resultado
app.PreprocessedData = filtered;
app.PreprocessedColumnName = nombreColumna{1};
        end

        % Button pushed function: SiguienteButton
        function SiguienteButtonPushed(app, event)
% 1) Cambiar de pestaña
app.TabGroup.SelectedTab = app.ButterworthTab;

% 2) Verificar que existan los datos preprocesados
    if isempty(app.PreprocessedData)
        uialert(app.UIFigure, 'Primero aplica el filtro Hampel antes de continuar.', 'Error');
        return;
    end

    % 3) Parámetros del filtro Butterworth
    fs = 100;        % Frecuencia de muestreo (Hz) — ajusta según tu sensor
    cutoff = 8;      % Frecuencia de corte (Hz)
    order = 4;       % Orden del filtro

    % 4) Obtener señal filtrada por Hampel
    signal_hampel = app.PreprocessedData(:);

    % 5) Aplicar filtro Butterworth
    filtered_butter = app.butter_lowpass_filter(signal_hampel, cutoff, fs, order);

    % 6) Graficar en la pestaña Butterworth
    ax = app.UIAxes5;  % Asegúrate de usar el nombre real del axes
    cla(ax);
    plot(ax, app.inputData.(app.PreprocessedColumnName), 'Color',[0.3010 0.7450 0.9330], 'DisplayName', 'Original');
    hold(ax, 'on');
    plot(ax, signal_hampel, 'r', 'DisplayName', 'Hampel');
    plot(ax, filtered_butter, 'g', 'LineWidth', 1.2, 'DisplayName', 'Butterworth');
    hold(ax, 'off');
    grid(ax, 'on');
    legend(ax, 'show');
    title(ax, 'Filtro Hampel + Butterworth');
    xlabel(ax, 'Muestras');
    ylabel(ax, 'Aceleración (g)');

% --- Guardar resultado completo como tabla ---
filtered_table = app.inputData;  % copia el dataset original
filtered_table.("Acceleration Y (g)") = filtered_butter;  % reemplaza la columna filtrada Y

app.ButterFilteredData = filtered_table;  % guarda la tabla completa
        end

        % Button pushed function: MODOAButton
        function MODOAButtonPushed(app, event)
        % 1) Cambiar de pestaña
        app.TabGroup.SelectedTab = app.ImportarTab;

        end

        % Button pushed function: ExtraerCaracteristicasButton
        function ExtraerCaracteristicasButtonPushed(app, event)
app.TabGroup.SelectedTab = app.CaracteristicasTab;

    % --- Validar ventaneo ---
    if isempty(app.SegmentedData)
        uialert(app.UIFigure, 'Primero realiza el ventaneo.', 'Error');
        return;
    end

    num_segments = numel(app.SegmentedData);

    % --- Detectar columnas relevantes ---
    vars = app.inputData.Properties.VariableNames;
    target_columns = vars(contains(vars, 'Acceleration', 'IgnoreCase', true) | ...
                      contains(vars, 'AngularVelocity', 'IgnoreCase', true) | ...
                      contains(vars, 'Angle', 'IgnoreCase', true));

    if isempty(target_columns)
        uialert(app.UIFigure, 'No se encontraron columnas de aceleración o velocidad angular.', 'Error');
        return;
    end

    % --- Inicializar lista de características ---
    feature_list = cell(num_segments, 1);

    for i = 1:num_segments
        segment = app.SegmentedData{i};

        if isempty(segment)
            feature_list{i} = nan(1, numel(target_columns) * 9);
            continue;
        end

        % --- Convertir segmento a tabla ---
        if istable(segment)
            % Ya es tabla, usa solo las columnas relevantes
            cols_to_use = intersect(segment.Properties.VariableNames, target_columns);
            if isempty(cols_to_use)
                warning('Segmento %d no tiene columnas válidas.', i);
                feature_list{i} = nan(1, numel(target_columns) * 9);
                continue;
            end
            segment_table = segment(:, cols_to_use);

        elseif isnumeric(segment)
            % Es una matriz numérica
            n_cols = size(segment, 2);
            n_targets = numel(target_columns);
            n_min = min(n_cols, n_targets);

            % Ajustar número de columnas si sobran o faltan
            segment_table = array2table(segment(:, 1:n_min), ...
                'VariableNames', target_columns(1:n_min));

        else
            error('Tipo de datos de segmento no válido.');
        end

        % --- Extraer características ---
        try
            features = app.extract_features_matlab(segment_table, segment_table.Properties.VariableNames);
        catch ME
            warning('Error al extraer características en segmento %d: %s', i, ME.message);
            feature_list{i} = nan(1, numel(target_columns) * 9);
            continue;
        end

        % --- Convertir a vector numérico ---
        if isstruct(features)
            vals = struct2array(features);
        else
            vals = features;
        end

        feature_list{i} = vals(:)'; % vector fila
    end

    % --- Convertir lista a matriz ---
    feature_matrix = cell2mat(feature_list);

    % --- Crear nombres de columnas personalizados ---
    desired_features = { ...
        'Acceleration Z(g)_entropy', ...
        'Acceleration Z(g)_std', ...
        'Acceleration Z(g)_var', ...
        'Acceleration Y(g)_std', ...
        'Acceleration Y(g)_var', ...
        'Angular velocity Y(°/s)_energy', ...
        'Angular velocity Y(°/s)_std', ...
        'Angular velocity Y(°/s)_var', ...
        'Angular velocity Y(°/s)_MAV', ...
        'Acceleration Y(g)_entropy'};

    % --- Verificar tamaño ---
    num_desired = numel(desired_features);
    num_actual = size(feature_matrix, 2);

    % Si tu matriz tiene más columnas, selecciona las 10 primeras (ajustar si sabes su orden exacto)
    if num_actual >= num_desired
        feature_matrix = feature_matrix(:, 1:num_desired);
    else
        % Si faltan columnas, rellena con NaN
        feature_matrix = [feature_matrix nan(size(feature_matrix, 1), num_desired - num_actual)];
    end

    % --- Crear tabla final con nombres personalizados ---
    T = array2table(feature_matrix, ...
        'VariableNames', desired_features, ...
        'RowNames', compose('Window_%d', 1:num_segments));

    % --- Mostrar en la UI ---
    app.UITable.Data = T;
    app.UITable.ColumnName = T.Properties.VariableNames; 
    app.UITable.RowName = T.Properties.RowNames;

% --- Guardar la tabla en la app ---
app.FeatureTable = T;

% --- Guardar CSV etiquetable ---
csvName = fullfile(pwd, 'caracteristicas_labeled.csv');
T.label = repmat("", height(T), 1); 
writetable(T, csvName);
disp(['Archivo CSV guardado: ', csvName]);

% --- Mensaje de éxito ---
uialert(app.UIFigure, sprintf(['Características extraídas correctamente (%d ventanas × %d características).\n' ...
    'Se guardó el archivo %s para etiquetarlo.'], ...
    num_segments, size(feature_matrix, 2), csvName), 'Éxito');
        end

        % Button pushed function: SiguienteButton_3
        function SiguienteButton_3Pushed(app, event)
   % 1) Cambiar de pestaña a "Ventaneo"
    app.TabGroup.SelectedTab = app.VentaneoTab;

    % 2) Verificar que haya datos filtrados
    if isempty(app.ButterFilteredData)
        uialert(app.UIFigure, 'Primero aplica el filtro Butterworth.', 'Error');
        return;
    end

    % 3) Definir número de ventanas
    num_windows = 5;  % o el número que uses

    % 4) Obtener todas las columnas relevantes
    data_table = app.ButterFilteredData;  % debe ser una tabla
    vars = data_table.Properties.VariableNames;

    target_columns = vars(contains(vars, 'Acceleration', 'IgnoreCase', true) | ...
                          contains(vars, 'AngularVelocity', 'IgnoreCase', true) | ...
                          contains(vars, 'Angle', 'IgnoreCase', true));

    data_table = data_table(:, target_columns);

    % 5) Usar tu función segment_data para dividir cada columna
    segments = cell(1, num_windows);
    n = height(data_table);

    for c = 1:width(data_table)
        col_data = data_table{:, c};
        col_segments = app.segment_data(col_data, num_windows);

        % Combinar los segmentos columna por columna
        for i = 1:num_windows
            if c == 1
                segments{i} = table(col_segments{i}, 'VariableNames', {data_table.Properties.VariableNames{c}});
            else
                segments{i}.(data_table.Properties.VariableNames{c}) = col_segments{i};
            end
        end
    end

    % 6) Guardar los segmentos en la app
    app.SegmentedData = segments;
    app.CurrentWindow = 1;
    app.NumWindows = num_windows;

    % 7) Graficar primera ventana
    ax = app.UIAxes6;
    cla(ax);
    plot(ax, app.SegmentedData{1}.("Acceleration Y (g)"), 'LineWidth', 1.2, 'Color', [0.2 0.6 0.9]);
    grid(ax, 'on');
    xlabel(ax, 'Índice dentro de la ventana');
    ylabel(ax, 'Aceleración Y (g)');
    title(ax, sprintf('Ventana %d de %d', app.CurrentWindow, num_windows));
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
 % --- Depuración ---
    disp('--- Depuración NextButton ---');
    if isprop(app, 'CurrentWindow')
        disp(['CurrentWindow antes: ', num2str(app.CurrentWindow)]);
    end

    % --- Verificar que existan los datos segmentados ---
    if isempty(app.SegmentedData)
        uialert(app.UIFigure, 'Primero realiza el ventaneo.', 'Error');
        return;
    end

    % --- Validar que CurrentWindow exista y sea válido ---
    if ~isprop(app, 'CurrentWindow') || isempty(app.CurrentWindow)
        app.CurrentWindow = 1;
    end

    % --- Aumentar contador ---
    app.CurrentWindow = app.CurrentWindow + 1;
    if app.CurrentWindow > app.NumWindows
        app.CurrentWindow = 1;
    end

    % --- Obtener el segmento actual ---
    segment = app.SegmentedData{app.CurrentWindow};

    % --- Verificar que el segmento sea tabla ---
    if ~istable(segment)
        uialert(app.UIFigure, sprintf('Segmento %d no es una tabla válida.', app.CurrentWindow), 'Error');
        return;
    end

    % --- Detectar el axes ---
    ax = app.UIAxes6;
    cla(ax);

    % --- Variables de la tabla ---
    vars = segment.Properties.VariableNames;

    % --- Seleccionar columna preferida ---
    preferredCol = 'Acceleration Y (g)';
    if any(strcmp(preferredCol, vars))
        y = segment.(preferredCol);
        label = preferredCol;
    else
        y = segment{:, 1};
        label = vars{1};
    end

    % --- Graficar ---
    plot(ax, y, 'Color', [0.2 0.6 0.9], 'LineWidth', 1.2);
    grid(ax, 'on');
    xlabel(ax, 'Índice dentro de la ventana');
    ylabel(ax, label);
    title(ax, sprintf('Ventana %d de %d', app.CurrentWindow, app.NumWindows));

    % --- Confirmar en consola ---
    disp(['✅ Mostrando ventana ', num2str(app.CurrentWindow), ' de ', num2str(app.NumWindows)]);
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
    % Cambiar a la pestaña de Clasificación
    app.TabGroup.SelectedTab = app.ClasificacionTab;

    % Verificar que existen las características
    if isempty(app.FeatureTable)
        uialert(app.UIFigure, 'Primero extrae las características.', 'Error');
        return;
    end

    % Convertir a matriz numérica
    X = table2array(app.FeatureTable);

    % --- Cargar modelo ---
    try
        modelo = load('logistic_model.mat');
    catch
        uialert(app.UIFigure, 'No se pudo cargar el modelo logistic_model.mat.', 'Error');
        return;
    end

    % Extraer coeficientes e intercepto
    coef = modelo.coefficients;   % tamaño: nClases × nCaracterísticas
    intercept = modelo.intercept; % tamaño: 1 × nClases

    % --- Mostrar tamaños para verificar compatibilidad ---
    disp('Tamaños:')
    disp(size(X))
    disp(size(coef))
    disp(size(intercept))

    % --- Ajustar dimensiones si no coinciden ---
    if size(X, 2) ~= size(coef, 2)
        warning('⚠️ Las dimensiones no coinciden. Ajustando columnas...');
        minCols = min(size(X, 2), size(coef, 2));
        X = X(:, 1:minCols);
        coef = coef(:, 1:minCols);
    end

    % --- Calcular puntuaciones (logits) ---
    scores = X * coef' + intercept;

    % --- Obtener la clase con mayor puntuación ---
    [~, pred_idx] = max(scores, [], 2);

    % --- Definir etiquetas ---
    class_labels = {'AB', 'ID', 'SM'};
    predicted_labels = class_labels(pred_idx);

    % --- Asegurar tamaños compatibles ---
    nVentanas = height(app.FeatureTable);
    nPred = numel(predicted_labels);

    if nPred ~= nVentanas
        warning('⚠️ Se detectó un desajuste: %d etiquetas vs %d ventanas. Ajustando...', nPred, nVentanas);
        minLen = min(nVentanas, nPred);
        predicted_labels = predicted_labels(1:minLen);
        ventanas = app.FeatureTable.Properties.RowNames(1:minLen);
    else
        ventanas = app.FeatureTable.Properties.RowNames;
    end

    % --- Crear tabla de resultados ---
    resultsTable = table(ventanas, predicted_labels', ...
        'VariableNames', {'Ventana', 'Etiqueta'});

    % --- Determinar etiqueta final (la más repetida) ---
    final_label = mode(categorical(predicted_labels));

    % --- Mostrar en la UI ---
    app.UITable2.Data = resultsTable;
    app.UITable2.ColumnName = resultsTable.Properties.VariableNames;

    % --- Mostrar resultado global ---
    archivo = "el archivo cargado"; % ← texto genérico si no hay nombre guardado
    if isprop(app, 'FileName')
        archivo = app.FileName;
    elseif isprop(app, 'FilePath')
        [~, archivo, ext] = fileparts(app.FilePath);
        archivo = archivo + ext;
    end

    mensaje = sprintf('El %s corresponde al movimiento "%s".', archivo, string(final_label));

    if isprop(app, 'ResultadoTextArea')
        app.ResultadoTextArea.Value = mensaje;
    else
        uialert(app.UIFigure, mensaje, 'Resultado');
    end

    disp('Clasificación completada correctamente ✅');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ModoTab = uitab(app.TabGroup);
            app.ModoTab.Title = 'Modo';
            app.MODOAButton = uibutton(app.ModoTab, 'push');
            app.MODOAButton.ButtonPushedFcn = createCallbackFcn(app, @MODOAButtonPushed, true);
            app.MODOAButton.Text = 'MODO A';
            app.MODOBButton = uibutton(app.ModoTab, 'push');
            app.MODOBButton.Text = 'MODO B';
            app.ImportarTab = uitab(app.TabGroup);
            app.ImportarTab.Title = 'Importar';
            app.UIAxes2 = uiaxes(app.ImportarTab);
            title(app.UIAxes2, 'Acceleration Y(g)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ImportButton = uibutton(app.ImportarTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PreprocesarButton = uibutton(app.ImportarTab, 'push');
            app.PreprocesarButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesarButtonPushed, true);
            app.PreprocesarButton.Text = 'Preprocesar';
            app.HampelTab = uitab(app.TabGroup);
            app.HampelTab.Title = 'Hampel';
            app.UIAxes4 = uiaxes(app.HampelTab);
            title(app.UIAxes4, 'Acceleration Y(g)')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.SiguienteButton = uibutton(app.HampelTab, 'push');
            app.SiguienteButton.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton.Text = 'Siguiente';
            app.ButterworthTab = uitab(app.TabGroup);
            app.ButterworthTab.Title = 'Butterworth';
            app.UIAxes5 = uiaxes(app.ButterworthTab);
            title(app.UIAxes5, 'Acceleration Y(g)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SiguienteButton_3 = uibutton(app.ButterworthTab, 'push');
            app.SiguienteButton_3.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButton_3Pushed, true);
            app.SiguienteButton_3.Text = 'Siguiente';
            app.VentaneoTab = uitab(app.TabGroup);
            app.VentaneoTab.Title = 'Ventaneo';
            app.UIAxes6 = uiaxes(app.VentaneoTab);
            title(app.UIAxes6, 'Acceleration Y(g)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ExtraerCaracteristicasButton = uibutton(app.VentaneoTab, 'push');
            app.ExtraerCaracteristicasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerCaracteristicasButtonPushed, true);
            app.ExtraerCaracteristicasButton.Text = 'Extraer Caracteristicas';
            app.NextButton = uibutton(app.VentaneoTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CaracteristicasTab = uitab(app.TabGroup);
            app.CaracteristicasTab.Title = 'Caracteristicas';
            app.UITable = uitable(app.CaracteristicasTab);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.ClasificarButton = uibutton(app.CaracteristicasTab, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.ClasificacionTab = uitab(app.TabGroup);
            app.ClasificacionTab.Title = 'Clasificacion';
            app.UITable2 = uitable(app.ClasificacionTab);
            app.UITable2.ColumnName = {''; ''; ''; ''};
            app.UITable2.RowName = {};
            app.ClasificacinLabel = uilabel(app.ClasificacionTab);
            app.ClasificacinLabel.Text = 'Clasificación:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ModoA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end