classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProyectoIntegrativoIIUIFigure  matlab.ui.Figure
        SistemadeCapturaProcesamientoyClasificacindeMovimientosdeManoLabel  matlab.ui.control.Label
        ModoBButton                    matlab.ui.control.Button
        ModoAButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        ChildApp % Description
    end
    
    methods (Access = private)
        
        function startupFcn(app)
        end
        function launchChild(app, classOrBaseName)
            % Ocultar menú si existe una UIFigure válida
            menuFig = [];
            if isprop(app,'UIFigure') && ~isempty(app.UIFigure) && isvalid(app.UIFigure)
                menuFig = app.UIFigure;
                menuFig.Visible = 'off';
            end
        
            try
                % === 1) Intentar abrir como clase ===
                if exist(classOrBaseName,'class')
                    child = feval(classOrBaseName);
                    try
                        % Enganchar al cierre
                        if isprop(child,'UIFigure') && ~isempty(child.UIFigure)
                            f = child.UIFigure;
                            oldCRF = f.CloseRequestFcn;
                            f.CloseRequestFcn = @(src,evt) onChildClose(oldCRF, src, evt, menuFig);
                        end
                    catch ME_inner
                        % warning detallado si falla el hook
                        warning(ME_inner.identifier, ...
                            'Error al adjuntar cierre a %s:\n%s\nArchivo: %s (línea %d)', ...
                            classOrBaseName, ME_inner.message, ME_inner.stack(1).file, ME_inner.stack(1).line);
                    end
                    return
                end
        
                % === 2) Fallback: archivo .mlapp ===
                mlappFile = classOrBaseName;
                if ~endsWith(mlappFile,'.mlapp','IgnoreCase',true)
                    mlappFile = mlappFile + ".mlapp";
                end
                if isfile(mlappFile)
                    run(mlappFile);
                    if ~isempty(menuFig)
                        menuFig.Visible = 'on';
                        figure(menuFig);
                    end
                    return
                end
        
                % === 3) No encontrado ===
                msg = sprintf("No se encontró clase ni archivo: %s", classOrBaseName);
                if ~isempty(menuFig)
                    uialert(menuFig, msg, "No encontrado");
                else
                    warning(msg);
                end
        
            catch ME
                % === Captura de excepción general con detalles ===
                if ~isempty(menuFig)
                    menuFig.Visible = 'on';
                end
        
                % Mostrar como warning con info del stack
                if ~isempty(ME.stack)
                    s = ME.stack(1);
                    warning(ME.identifier, ...
                        'Error al abrir %s:\n%s\nArchivo: %s (línea %d)', ...
                        classOrBaseName, ME.message, s.file, s.line);
                else
                    warning(ME.identifier, 'Error al abrir %s:\n%s', classOrBaseName, ME.message);
                end
            end
        end
        
        function onChildClose(oldCRF, src, evt, menuFig)
            try
                if isa(oldCRF,'function_handle')
                    oldCRF(src, evt);
                else
                    delete(src);
                end
            catch ME
                warning(ME.identifier, ...
                    'Error al cerrar ventana hija:\n%s\nArchivo: %s (línea %d)', ...
                    ME.message, ME.stack(1).file, ME.stack(1).line);
                try, delete(src); end
            end
            if ~isempty(menuFig) && isvalid(menuFig)
                menuFig.Visible = 'on';
                figure(menuFig);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModoAButton
        function ModoAButtonPushed(app, event)
            app.launchChild('ModoA');
        end

        % Button pushed function: ModoBButton
        function ModoBButtonPushed(app, event)
            app.launchChild('app6');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProyectoIntegrativoIIUIFigure = uifigure('Visible', 'off');
            app.ProyectoIntegrativoIIUIFigure.Name = 'Proyecto Integrativo II';
            app.ModoAButton = uibutton(app.ProyectoIntegrativoIIUIFigure, 'push');
            app.ModoAButton.ButtonPushedFcn = createCallbackFcn(app, @ModoAButtonPushed, true);
            app.ModoAButton.Text = 'Modo A';
            app.ModoBButton = uibutton(app.ProyectoIntegrativoIIUIFigure, 'push');
            app.ModoBButton.ButtonPushedFcn = createCallbackFcn(app, @ModoBButtonPushed, true);
            app.ModoBButton.Text = 'Modo B';
            app.SistemadeCapturaProcesamientoyClasificacindeMovimientosdeManoLabel = uilabel(app.ProyectoIntegrativoIIUIFigure);
            app.SistemadeCapturaProcesamientoyClasificacindeMovimientosdeManoLabel.Text = 'Sistema de Captura, Procesamiento y Clasificación de Movimientos de Mano';
            % Show the figure after all components are created
            app.ProyectoIntegrativoIIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProyectoIntegrativoIIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProyectoIntegrativoIIUIFigure)
        end
    end
end