classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CapturadedatosTab             matlab.ui.container.Tab
        EjesListBox                   matlab.ui.control.ListBox
        EjesListBoxLabel              matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        CalibrarsensorButton          matlab.ui.control.Button
        EncontrarsensorButton         matlab.ui.control.Button
        GuardardatosButton            matlab.ui.control.Button
        DetenercapturaButton          matlab.ui.control.Button
        PreprocesarButton             matlab.ui.control.Button
        IniciarcapturaButton          matlab.ui.control.Button
        UIAxes2_2                     matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        HampelTab                     matlab.ui.container.Tab
        SiguienteButton_2             matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        ButterworthTab                matlab.ui.container.Tab
        SiguienteButton_3             matlab.ui.control.Button
        UIAxes5                       matlab.ui.control.UIAxes
        VentaneoTab                   matlab.ui.container.Tab
        NextButton                    matlab.ui.control.Button
        ExtraerCaracteristicasButton  matlab.ui.control.Button
        UIAxes6                       matlab.ui.control.UIAxes
        CaracteristicasTab            matlab.ui.container.Tab
        Caracteristicas               matlab.ui.control.Table
        ClasificacionTab              matlab.ui.container.Tab
        UIAxes7_2                     matlab.ui.control.UIAxes
    end

    properties (Access = public)
    inputData   % lo usas para almacenar datos capturados
    UITable matlab.ui.control.Table
end
    properties (Access = private)
    
    Mode char = 'B';

    MenuModo matlab.ui.container.Menu
    MenuModoA matlab.ui.container.Menu
    MenuModoB matlab.ui.container.Menu

   
    % --- BLE ---
    cNotify = []              % characteristic de notificación (FFE4)
    bleObj  = []              % objeto BLE
    cWrite  = []              % characteristic de escritura (FFE9)
    SelectedSensor string = ""   % nombre del sensor
    isCapturing logical = false  % indica si hay captura activa
    deviceName string = ""       % nombre del dispositivo conectado

    % --- Tiempos ---
    startTime datetime = datetime.empty      % tiempo de inicio de captura
    prevAccTime datetime = datetime.empty    % última muestra ACC
    prevGyrTime datetime = datetime.empty    % última muestra GYR
    prevAngTime datetime = datetime.empty    % última muestra ANG

    % --- Acelerómetro ---
    accBuf double = []         %  datos de Ax, Ay, Az (g)
    accIdx double = []         %  índice de muestra
    tAcc_sec double = []       % tiempo relativo (s)
    dtAcc_sec double = []      % delta t entre muestras
    sampleCounterAcc double = 0

    % --- Giroscopio ---
    gyrBuf double = []         %  datos de Gx, Gy, Gz (°/s)
    gyrIdx double = []         %  índice de muestra
    tGyr_sec double = []       % tiempo relativo (s)
    dtGyr_sec double = []      % delta t entre muestras
    sampleCounterGyr double = 0

    % --- Ángulos ---
    angBuf double = []         %  datos de AngX, AngY, AngZ (°)
    angIdx double = []         %  índice de muestra
    tAng_sec double = []       % tiempo relativo (s)
    dtAng_sec double = []      % delta t entre muestras
    sampleCounterAng double = 0

    sampleRate_Hz double = 100;
    notifyCount double = 0;

    LastSavedPath string = ""

    PreprocessedData double = []
    PreprocessedColumnName char = ''
    ButterFilteredData table = table()

    SegmentedData cell ={}
    CurrentWindow double= 0
    NumWindows double = 0

    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  
            app.inputData = table();
        end
    
        function localWrite(app, char, data)
            write(char, data);
        end 
    
        function localHandleData(app, evt)
            
            bytes = uint8(evt.Data);
            n = numel(bytes);
            i = 1;
    
            while i+10 <= n
                if bytes(i) == hex2dec('55')
                    id = bytes(i+1);
                    fr = bytes(i:i+10);  
                    switch id
                        case hex2dec('51')  % ACC
                            ax = typecast(uint8(fr(3:4)),'int16');
                            ay = typecast(uint8(fr(5:6)),'int16');
                            az = typecast(uint8(fr(7:8)),'int16');
                            acc = double([ax ay az]) / 32768 * 16;  % ±16 g
                            app.sampleCounterAcc = app.sampleCounterAcc + 1;
                            app.accBuf(end+1,:) = acc;
                            app.accIdx(end+1,1) = app.sampleCounterAcc;
    
                        case hex2dec('52')  % GYRO
                            gx = typecast(uint8(fr(3:4)),'int16');
                            gy = typecast(uint8(fr(5:6)),'int16');
                            gz = typecast(uint8(fr(7:8)),'int16');
                            gyr = double([gx gy gz]) / 32768 * 2000; % ±2000 °/s
                            app.sampleCounterGyr = app.sampleCounterGyr + 1;
                            app.gyrBuf(end+1,:) = gyr;
                            app.gyrIdx(end+1,1) = app.sampleCounterGyr;
                    end
                    i = i + 11;
                else
                    i = i + 1;
                end
            end
        end  
    
        function onBleData(app, src, ~)
           
            try
                [bytes, ts] = read(src, "latest");
            catch
                try
                    bytes = read(src);
                    ts = datetime('now');
                catch
                    return;
                end
            end
            if isempty(bytes), return; end
            bytes = uint8(bytes(:)).';   
        
            % Conteo de notificaciones (debug)
            app.notifyCount = app.notifyCount + 1;
            if app.notifyCount <= 5 || mod(app.notifyCount,50) ==0
                fprintf('[BLE] notif #%d, %d bytes\n', app.notifyCount, numel(bytes));
            end
        
            % Buffer incremental para parseo
            persistent rxBuf
            if isempty(rxBuf), rxBuf = uint8([]); end
            rxBuf = [rxBuf, bytes]; %#ok<AGROW>
        
            % Tiempo relativo (s) desde startTime
            if isempty(app.startTime), app.startTime = datetime('now'); end
            tRel = seconds(ts - app.startTime);
        
            i = 1;
            n = numel(rxBuf);
            while i <= n
                rem = n - i + 1;
        
                % --- Formato B: 20 bytes, 2º byte == 0x61 ---
                if rem >= 20 && rxBuf(i+1) == hex2dec('61')
                    fr = rxBuf(i:i+19);
        
                    
                    getS16 = @(hi,lo) double(typecast(uint16(bitor(bitshift(uint16(hi),8),uint16(lo))), 'int16'));
        
                    % Acel/gyro
                    axg = getS16(fr(4), fr(3))  / 32768 * 16;
                    ayg = getS16(fr(6), fr(5))  / 32768 * 16;
                    azg = getS16(fr(8), fr(7))  / 32768 * 16;
        
                    gxd = getS16(fr(10),fr(9))  / 32768 * 2000;
                    gyd = getS16(fr(12),fr(11)) / 32768 * 2000;
                    gzd = getS16(fr(14),fr(13)) / 32768 * 2000;
        
                    % Ángulos
                    angx = getS16(fr(16),fr(15)) / 32768 * 180;
                    angy = getS16(fr(18),fr(17)) / 32768 * 180;
                    angz = getS16(fr(20),fr(19)) / 32768 * 180;
        
                    % ---- ACC ----
                    app.sampleCounterAcc = app.sampleCounterAcc + 1;
                    app.accBuf(end+1,:)  = [axg ayg azg]; %#ok<AGROW>
                    app.accIdx(end+1,1)  = app.sampleCounterAcc; %#ok<AGROW>
                    if isempty(app.tAcc_sec), app.tAcc_sec = tRel; app.dtAcc_sec = NaN;
                    else, app.tAcc_sec(end+1,1)=tRel; app.dtAcc_sec(end+1,1)=app.tAcc_sec(end)-app.tAcc_sec(end-1); end
        
                    % ---- GYR ----
                    app.sampleCounterGyr = app.sampleCounterGyr + 1;
                    app.gyrBuf(end+1,:)  = [gxd gyd gzd]; %#ok<AGROW>
                    app.gyrIdx(end+1,1)  = app.sampleCounterGyr; %#ok<AGROW>
                    if isempty(app.tGyr_sec), app.tGyr_sec = tRel; app.dtGyr_sec = NaN;
                    else, app.tGyr_sec(end+1,1)=tRel; app.dtGyr_sec(end+1,1)=app.tGyr_sec(end)-app.tGyr_sec(end-1); end
        
                    % ---- ANG ----
                    app.sampleCounterAng = app.sampleCounterAng + 1;
                    app.angBuf(end+1,:)  = [angx angy angz]; %#ok<AGROW>
                    app.angIdx(end+1,1)  = app.sampleCounterAng; %#ok<AGROW>
                    if isempty(app.tAng_sec), app.tAng_sec = tRel; app.dtAng_sec = NaN;
                    else, app.tAng_sec(end+1,1)=tRel; app.dtAng_sec(end+1,1)=app.tAng_sec(end)-app.tAng_sec(end-1); end
        
                    i = i + 20;
                    continue
                end
        
                % --- Formato A: 11 bytes (0x55 ID …) ---
                if rem >= 11 && rxBuf(i) == hex2dec('55')
                    fr = rxBuf(i:i+10);
                    id = fr(2);
        
                    if id == hex2dec('51')   % ACC
                        ax = typecast(uint8(fr(3:4)),'int16');
                        ay = typecast(uint8(fr(5:6)),'int16');
                        az = typecast(uint8(fr(7:8)),'int16');
                        acc = double([ax ay az]) / 32768 * 16;
        
                        app.sampleCounterAcc = app.sampleCounterAcc + 1;
                        app.accBuf(end+1,:)  = acc; %#ok<AGROW>
                        app.accIdx(end+1,1)  = app.sampleCounterAcc; %#ok<AGROW>
                        if isempty(app.tAcc_sec), app.tAcc_sec = tRel; app.dtAcc_sec = NaN;
                        else, app.tAcc_sec(end+1,1)=tRel; app.dtAcc_sec(end+1,1)=app.tAcc_sec(end)-app.tAcc_sec(end-1); end
        
                    elseif id == hex2dec('52') % GYR
                        gx = typecast(uint8(fr(3:4)),'int16');
                        gy = typecast(uint8(fr(5:6)),'int16');
                        gz = typecast(uint8(fr(7:8)),'int16');
                        gyr = double([gx gy gz]) / 32768 * 2000;
        
                        app.sampleCounterGyr = app.sampleCounterGyr + 1;
                        app.gyrBuf(end+1,:)  = gyr; %#ok<AGROW>
                        app.gyrIdx(end+1,1)  = app.sampleCounterGyr; %#ok<AGROW>
                        if isempty(app.tGyr_sec), app.tGyr_sec = tRel; app.dtGyr_sec = NaN;
                        else, app.tGyr_sec(end+1,1)=tRel; app.dtGyr_sec(end+1,1)=app.tGyr_sec(end)-app.tGyr_sec(end-1); end
        
                    elseif id == hex2dec('53') % ANG
                        angx = typecast(uint8(fr(3:4)),'int16');
                        angy = typecast(uint8(fr(5:6)),'int16');
                        angz = typecast(uint8(fr(7:8)),'int16');
                        ang  = double([angx angy angz]) / 32768 * 180;
        
                        app.sampleCounterAng = app.sampleCounterAng + 1;
                        app.angBuf(end+1,:)  = ang; %#ok<AGROW>
                        app.angIdx(end+1,1)  = app.sampleCounterAng; %#ok<AGROW>
                        if isempty(app.tAng_sec), app.tAng_sec = tRel; app.dtAng_sec = NaN;
                        else, app.tAng_sec(end+1,1)=tRel; app.dtAng_sec(end+1,1)=app.tAng_sec(end)-app.tAng_sec(end-1); end
                    end
        
                    i = i + 11;
                    continue
                end
        
                % Si no hay frame completo todavía, salimos
                break
            end
        
           
            rxBuf = rxBuf(i:end);
        end
        function T = buildExportTable(app)
            % Alinea por el mayor número de muestras entre Acc/Gyr/Ang y
            % arma una sola tabla con metadatos, tiempos relativos e índices.
        
            nA = size(app.accBuf,1);
            nG = size(app.gyrBuf,1);
            nN = size(app.angBuf,1);
            N  = max([nA nG nN]);
            if N==0
                error("No hay datos en buffers.");
            end
        
            % Prealocar columnas (NaN donde no aplique)
            Device   = repmat(string(app.deviceName), N, 1);
            StartedAt= repmat(string(datestr(app.startTime,'yyyy-mm-dd HH:MM:SS.FFF')), N, 1);
        
            % ACC
            IdxAcc = nan(N,1); tAcc_s = nan(N,1); dtAcc_s = nan(N,1);
            Ax_g   = nan(N,1); Ay_g   = nan(N,1); Az_g    = nan(N,1);
            if nA>0
                IdxAcc(1:nA) = app.accIdx;
                Ax_g(1:nA) = app.accBuf(:,1);  Ay_g(1:nA) = app.accBuf(:,2);  Az_g(1:nA) = app.accBuf(:,3);
                if ~isempty(app.tAcc_sec),  tAcc_s(1:nA)  = app.tAcc_sec;  end
                if ~isempty(app.dtAcc_sec), dtAcc_s(1:nA) = app.dtAcc_sec; end
            end
        
            % GYR
            IdxGyr = nan(N,1); tGyr_s = nan(N,1); dtGyr_s = nan(N,1);
            Gx_dps = nan(N,1); Gy_dps = nan(N,1); Gz_dps = nan(N,1);
            if nG>0
                IdxGyr(1:nG) = app.gyrIdx;
                Gx_dps(1:nG) = app.gyrBuf(:,1);  Gy_dps(1:nG) = app.gyrBuf(:,2);  Gz_dps(1:nG) = app.gyrBuf(:,3);
                if ~isempty(app.tGyr_sec),  tGyr_s(1:nG)  = app.tGyr_sec;  end
                if ~isempty(app.dtGyr_sec), dtGyr_s(1:nG) = app.dtGyr_sec; end
            end
        
            % ANG (si no los usas, puedes comentar esta sección)
            IdxAng   = nan(N,1); tAng_s = nan(N,1); dtAng_s = nan(N,1);
            AngX_deg = nan(N,1); AngY_deg = nan(N,1); AngZ_deg = nan(N,1);
            if nN>0
                IdxAng(1:nN)   = app.angIdx;
                AngX_deg(1:nN) = app.angBuf(:,1);  AngY_deg(1:nN) = app.angBuf(:,2);  AngZ_deg(1:nN) = app.angBuf(:,3);
                if ~isempty(app.tAng_sec),  tAng_s(1:nN)  = app.tAng_sec;  end
                if ~isempty(app.dtAng_sec), dtAng_s(1:nN) = app.dtAng_sec; end
            end
        
            % Armar tabla final
            T = table( ...
                Device, StartedAt, ...
                IdxAcc, tAcc_s, dtAcc_s, Ax_g, Ay_g, Az_g, ...
                IdxGyr, tGyr_s, dtGyr_s, Gx_dps, Gy_dps, Gz_dps, ...
                IdxAng, tAng_s, dtAng_s, AngX_deg, AngY_deg, AngZ_deg);
        end
        function plotFromTable(app, T)
            % Limpia ejes
            cla(app.UIAxes2); cla(app.UIAxes2_2);
        
            % Obtener qué ejes graficar
            try
                selectedAxes = app.EjesListBox.Value;
            catch
                selectedAxes = {'Ax','Ay','Az','Gx','Gy','Gz'}; % por si no existe
            end
        
            % ===== Acelerómetro =====
            hold(app.UIAxes2, 'off');
            maskA = ~isnan(T.IdxAcc) & all(~isnan([T.Ax_g T.Ay_g T.Az_g]),2);
            if any(maskA)
                if ismember('Ax',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ax_g(maskA)); hold(app.UIAxes2,'on'); end
                if ismember('Ay',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ay_g(maskA)); hold(app.UIAxes2,'on'); end
                if ismember('Az',selectedAxes), plot(app.UIAxes2, T.IdxAcc(maskA), T.Az_g(maskA)); hold(app.UIAxes2,'on'); end
                title(app.UIAxes2,'Aceleración (g)');
                xlabel(app.UIAxes2,'Muestras'); ylabel(app.UIAxes2,'g');
                legend(app.UIAxes2, intersect({'Ax','Ay','Az'},selectedAxes), 'Location','best');
            else
                title(app.UIAxes2,'Aceleración (g) — sin datos');
            end
        
            % ===== Giroscopio =====
            hold(app.UIAxes2_2, 'off');
            maskG = ~isnan(T.IdxGyr) & all(~isnan([T.Gx_dps T.Gy_dps T.Gz_dps]),2);
            if any(maskG)
                if ismember('Gx',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gx_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                if ismember('Gy',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gy_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                if ismember('Gz',selectedAxes), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gz_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                title(app.UIAxes2_2,'Giroscopio (°/s)');
                xlabel(app.UIAxes2_2,'Muestras'); ylabel(app.UIAxes2_2,'°/s');
                legend(app.UIAxes2_2, intersect({'Gx','Gy','Gz'},selectedAxes), 'Location','best');
            else
                title(app.UIAxes2_2,'Giroscopio (°/s) — sin datos');
            end
        
            drawnow;
        end 
        function filtered = hampel_filter(app, data, window_size)
                filtered = data;
                k = floor(window_size / 2);
                n = length(data);
        
                for i = (k + 1):(n - k)
                    window = data(i - k : i + k);
                    median_val = median(window, 'omitnan');
                    mad_val = median(abs(window - median_val), 'omitnan');
                    threshold = 1.4826 * mad_val;
        
                    if abs(data(i) - median_val) > threshold
                        filtered(i) = median_val;
                    end
                end    
        end
        function T = buildInputTableFromBuffers(app)
            % Arma una tabla con los nombres que ya usas al guardar
            nA = size(app.accBuf,1);
            nG = size(app.gyrBuf,1);
            nN = size(app.angBuf,1);
            N  = max([nA nG nN 0]);
        
            idxAcc = nan(N,1);  Ax = nan(N,1); Ay = nan(N,1); Az = nan(N,1);
            tAcc   = nan(N,1);  dtAcc = nan(N,1);
            idxGyr = nan(N,1);  Gx = nan(N,1); Gy = nan(N,1); Gz = nan(N,1);
            tGyr   = nan(N,1);  dtGyr = nan(N,1);
            idxAng = nan(N,1);  AngX = nan(N,1); AngY = nan(N,1); AngZ = nan(N,1);
            tAng   = nan(N,1);  dtAng = nan(N,1);
        
            if nA>0
                idxAcc(1:nA) = app.accIdx;
                Ax(1:nA)     = app.accBuf(:,1);
                Ay(1:nA)     = app.accBuf(:,2);
                Az(1:nA)     = app.accBuf(:,3);
                if ~isempty(app.tAcc_sec),  tAcc(1:nA)  = app.tAcc_sec;  end
                if ~isempty(app.dtAcc_sec), dtAcc(1:nA) = app.dtAcc_sec; end
            end
            if nG>0
                idxGyr(1:nG) = app.gyrIdx;
                Gx(1:nG)     = app.gyrBuf(:,1);
                Gy(1:nG)     = app.gyrBuf(:,2);
                Gz(1:nG)     = app.gyrBuf(:,3);
                if ~isempty(app.tGyr_sec),  tGyr(1:nG)  = app.tGyr_sec;  end
                if ~isempty(app.dtGyr_sec), dtGyr(1:nG) = app.dtGyr_sec; end
            end
            if nN>0
                idxAng(1:nN) = app.angIdx;
                AngX(1:nN)   = app.angBuf(:,1);
                AngY(1:nN)   = app.angBuf(:,2);
                AngZ(1:nN)   = app.angBuf(:,3);
                if ~isempty(app.tAng_sec),  tAng(1:nN)  = app.tAng_sec;  end
                if ~isempty(app.dtAng_sec), dtAng(1:nN) = app.dtAng_sec; end
            end
        
            devName = repmat(string(app.deviceName), N, 1);
            started = repmat(string(datestr(app.startTime,'yyyy-mm-dd HH:MM:SS.FFF')), N, 1);
        
            T = table( ...
                devName, started, ...
                idxAcc, tAcc, dtAcc, Ax, Ay, Az, ...
                idxGyr, tGyr, dtGyr, Gx, Gy, Gz, ...
                idxAng, tAng, dtAng, AngX, AngY, AngZ, ...
                'VariableNames', { ...
                    'Device','StartedAt', ...
                    'IdxAcc','tAcc_s','dtAcc_s','Ax_g','Ay_g','Az_g', ...
                    'IdxGyr','tGyr_s','dtGyr_s','Gx_dps','Gy_dps','Gz_dps', ...
                    'IdxAng','tAng_s','dtAng_s','AngX_deg','AngY_deg','AngZ_deg' ...
                });
        end 


            function y = butter_lowpass_filter(app, data, cutoff, fs, order)
                % Valida entradas
                data = double(data(:));
                if isempty(data) || ~isnumeric(data) || all(isnan(data))
                    error('butter_lowpass_filter:DataInvalid','La señal de entrada está vacía o no es numérica.');
                end
                if ~isscalar(fs) || ~isscalar(cutoff) || ~isscalar(order)
                    error('butter_lowpass_filter:ParamsInvalid','fs/cutoff/order deben ser escalares numéricos.');
                end
                if cutoff <= 0 || cutoff >= fs/2
                    error('butter_lowpass_filter:CutoffInvalid','cutoff debe estar entre (0, fs/2).');
                end
        
                % Filtro Butterworth
                nyq = 0.5*fs;
                Wn  = cutoff/nyq;
                [b,a] = butter(order, Wn, 'low');
                y = filtfilt(b, a, data);
            end
            function segments = segment_data(app, data, num_windows)
                % Divide un vector de datos en N ventanas iguales (la última incluye el resto)
                n = numel(data);
                if num_windows <= 0 || ~isscalar(num_windows)
                    error('num_windows debe ser un escalar positivo.');
                end
                window_size = floor(n / num_windows);
                if window_size < 1
                    % Si la serie es muy corta, crea 1 ventana con todo
                    num_windows = 1;
                    window_size = n;
                end
            
                segments = cell(1, num_windows);
                for i = 1:num_windows
                    start_idx = (i - 1) * window_size + 1;
                    if i < num_windows
                        end_idx = i * window_size;
                    else
                        end_idx = n; % incluir el resto
                    end
                    segments{i} = data(start_idx:end_idx);
                end
            end 
        function redrawSelectedAxes(app)
            % Lee selección actual (o usa todo por defecto)
            if isvalid(app.EjesListBox) && ~isempty(app.EjesListBox.Value)
                sel = string(app.EjesListBox.Value);
            else
                sel = ["Ax","Ay","Az","Gx","Gy","Gz"];
            end
        
            % --- Si hay tabla (p.ej. tras Guardar datos), graficar desde tabla ---
            if istable(app.inputData) && ~isempty(app.inputData)
                T = app.inputData;
                % Acelerómetro
                cla(app.UIAxes2); hold(app.UIAxes2,'off');
                if all(ismember(["IdxAcc","Ax_g","Ay_g","Az_g"], T.Properties.VariableNames))
                    maskA = ~isnan(T.IdxAcc) & all(~isnan([T.Ax_g T.Ay_g T.Az_g]),2);
                    if any(maskA)
                        if any(sel=="Ax"), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ax_g(maskA)); hold(app.UIAxes2,'on'); end
                        if any(sel=="Ay"), plot(app.UIAxes2, T.IdxAcc(maskA), T.Ay_g(maskA)); hold(app.UIAxes2,'on'); end
                        if any(sel=="Az"), plot(app.UIAxes2, T.IdxAcc(maskA), T.Az_g(maskA)); hold(app.UIAxes2,'on'); end
                        title(app.UIAxes2,'Aceleración (g)'); xlabel(app.UIAxes2,'Muestras'); ylabel(app.UIAxes2,'g');
                        lg = intersect(["Ax","Ay","Az"], sel, 'stable'); if ~isempty(lg), legend(app.UIAxes2, lg, 'Location','best'); end
                    else
                        title(app.UIAxes2,'Aceleración (g) — sin datos');
                    end
                else
                    title(app.UIAxes2,'Aceleración (g) — columnas no encontradas');
                end
        
                % Giroscopio
                cla(app.UIAxes2_2); hold(app.UIAxes2_2,'off');
                if all(ismember(["IdxGyr","Gx_dps","Gy_dps","Gz_dps"], T.Properties.VariableNames))
                    maskG = ~isnan(T.IdxGyr) & all(~isnan([T.Gx_dps T.Gy_dps T.Gz_dps]),2);
                    if any(maskG)
                        if any(sel=="Gx"), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gx_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                        if any(sel=="Gy"), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gy_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                        if any(sel=="Gz"), plot(app.UIAxes2_2, T.IdxGyr(maskG), T.Gz_dps(maskG)); hold(app.UIAxes2_2,'on'); end
                        title(app.UIAxes2_2,'Giroscopio (°/s)'); xlabel(app.UIAxes2_2,'Muestras'); ylabel(app.UIAxes2_2,'°/s');
                        lg = intersect(["Gx","Gy","Gz"], sel, 'stable'); if ~isempty(lg), legend(app.UIAxes2_2, lg, 'Location','best'); end
                    else
                        title(app.UIAxes2_2,'Giroscopio (°/s) — sin datos');
                    end
                else
                    title(app.UIAxes2_2,'Giroscopio (°/s) — columnas no encontradas');
                end
                drawnow;
                return
            end
        
            % --- Si NO hay tabla aún, graficar en vivo desde buffers ---
            cla(app.UIAxes2); cla(app.UIAxes2_2);
            % Acelerómetro
            if ~isempty(app.accBuf)
                hold(app.UIAxes2,'off');
                idxA = (1:size(app.accBuf,1)).';
                if any(sel=="Ax"), plot(app.UIAxes2, idxA, app.accBuf(:,1)); hold(app.UIAxes2,'on'); end
                if any(sel=="Ay"), plot(app.UIAxes2, idxA, app.accBuf(:,2)); hold(app.UIAxes2,'on'); end
                if any(sel=="Az"), plot(app.UIAxes2, idxA, app.accBuf(:,3)); hold(app.UIAxes2,'on'); end
                title(app.UIAxes2,'Aceleración (g)'); xlabel(app.UIAxes2,'Muestras'); ylabel(app.UIAxes2,'g');
                lg = intersect(["Ax","Ay","Az"], sel, 'stable'); if ~isempty(lg), legend(app.UIAxes2, lg, 'Location','best'); end
            else
                title(app.UIAxes2,'Aceleración (g) — sin datos');
            end
        
            % Giroscopio
            if ~isempty(app.gyrBuf)
                hold(app.UIAxes2_2,'off');
                idxG = (1:size(app.gyrBuf,1)).';
                if any(sel=="Gx"), plot(app.UIAxes2_2, idxG, app.gyrBuf(:,1)); hold(app.UIAxes2_2,'on'); end
                if any(sel=="Gy"), plot(app.UIAxes2_2, idxG, app.gyrBuf(:,2)); hold(app.UIAxes2_2,'on'); end
                if any(sel=="Gz"), plot(app.UIAxes2_2, idxG, app.gyrBuf(:,3)); hold(app.UIAxes2_2,'on'); end
                title(app.UIAxes2_2,'Giroscopio (°/s)'); xlabel(app.UIAxes2_2,'Muestras'); ylabel(app.UIAxes2_2,'°/s');
                lg = intersect(["Gx","Gy","Gz"], sel, 'stable'); if ~isempty(lg), legend(app.UIAxes2_2, lg, 'Location','best'); end
            else
                title(app.UIAxes2_2,'Giroscopio (°/s) — sin datos');
            end
            drawnow;
        end
        function feats = extract_features_matlab(app, x)
            % x: vector double (sin NaN)
            % Salida: fila [1x9] con las 9 características
        
            N = numel(x);
            if N == 0
                feats = nan(1,9);
                return;
            end
        
            mean_val = mean(x);
            std_val  = std(x);
            var_val  = var(x);
            energy_val = sum(x.^2) / N;
        
            % Entropía (histograma con probs > 0)
            [hc, ~] = histcounts(x, 30, 'Normalization', 'probability');
            hc = hc(hc > 0);
            if isempty(hc)
                entropy_val = NaN;
            else
                entropy_val = -sum(hc .* log2(hc));
            end
        
            skew_val = skewness(x);
            kurt_val = kurtosis(x);
            mav_val  = mean(abs(x));
        
            % Zero crossings: cambios de signo reales (ignora ceros)
            s = sign(x);
            s(s==0) = 1;                          % evita ambiguos
            zero_crossings = sum(diff(s) ~= 0);
        
            feats = [mean_val, std_val, var_val, energy_val, entropy_val, ...
                     skew_val, kurt_val, mav_val, zero_crossings];
       
        
        end

        % Button pushed function: IniciarcapturaButton
        function IniciarcapturaButtonPushed(app, event)
       
            % --- UUIDs del servicio y characteristics del WT901BLE ---
            SVC_FFE5 = "0000FFE5-0000-1000-8000-00805F9A34FB";
            CHR_FFE4 = "0000FFE4-0000-1000-8000-00805F9A34FB";  % Notify
            CHR_FFE9 = "0000FFE9-0000-1000-8000-00805F9A34FB";  % Write
        
            try
                %% 1) Validar estado y selección
                if app.isCapturing
                    uialert(app.UIFigure, "La captura ya está en curso.", "Aviso");
                    return;
                end
                if strlength(app.SelectedSensor) == 0
                    uialert(app.UIFigure, "Primero selecciona un sensor BLE.", "Falta selección");
                    return;
                end
        
                %% 2) Conectar
                fprintf("[BLE] Conectando a %s ...\n", app.SelectedSensor);
                app.bleObj = ble(app.SelectedSensor);
                pause(0.4);
                app.deviceName = string(app.bleObj.Name);
                fprintf("[BLE] Conectado a %s\n", app.deviceName);
        
                %% 3) Obtener las characteristics del servicio FFE5
                app.cNotify = characteristic(app.bleObj, SVC_FFE5, CHR_FFE4);
                app.cWrite  = characteristic(app.bleObj, SVC_FFE5, CHR_FFE9);
        
                %% 4) Configurar frecuencia a 100 Hz (comando FF AA 03 09 00)
                setRate100Hz = uint8([255 170 3 9 0]);
                try
                    write(app.cWrite, setRate100Hz, "withoutresponse");
                catch
                    write(app.cWrite, setRate100Hz);
                end
                pause(0.2);
                fprintf("[BLE] Frecuencia configurada: 100 Hz\n");
        
                %% 5) Iniciar transmisión continua (FF AA 27 3A 00)
                startCmd = uint8([255 170 39 58 0]);
                try
                    write(app.cWrite, startCmd, "withoutresponse");
                catch
                    write(app.cWrite, startCmd);
                end
                pause(0.3);
                fprintf("[BLE] Transmisión iniciada.\n");
        
                %% 6) Reset de buffers y contadores
                app.sampleRate_Hz = 100;
                app.notifyCount   = 0;
                app.startTime     = datetime('now');
        
                app.accBuf = zeros(0,3); app.accIdx = zeros(0,1); app.sampleCounterAcc = 0;
                app.gyrBuf = zeros(0,3); app.gyrIdx = zeros(0,1); app.sampleCounterGyr = 0;
                app.angBuf = zeros(0,3); app.angIdx = zeros(0,1); app.sampleCounterAng = 0;
        
                app.tAcc_sec = []; app.dtAcc_sec = [];
                app.tGyr_sec = []; app.dtGyr_sec = [];
                app.tAng_sec = []; app.dtAng_sec = [];
        
                %% 7) Suscribirse a notificaciones BLE
                subscribe(app.cNotify, "notification");
                app.cNotify.DataAvailableFcn = @(src,evt) app.onBleData(src,evt);
        
                %% 8) Configurar gráficos
                app.isCapturing = true;
                cla(app.UIAxes2); cla(app.UIAxes2_2);
                title(app.UIAxes2, 'Aceleración (g)');
                xlabel(app.UIAxes2, 'Muestras'); ylabel(app.UIAxes2, 'g');
        
                title(app.UIAxes2_2, 'Giroscopio (°/s)');
                xlabel(app.UIAxes2_2, 'Muestras'); ylabel(app.UIAxes2_2, '°/s');
        
                fprintf("[BLE] Captura iniciada a 100 Hz.\n");
                uialert(app.UIFigure, "Captura iniciada. Los datos se almacenan en memoria.", "En curso");
        
            catch ME
                %% 9) Limpieza en caso de error
                try
                    if ~isempty(app.cNotify)
                        app.cNotify.DataAvailableFcn = [];
                        unsubscribe(app.cNotify, "notification");
                    end
                end
                try
                    if ~isempty(app.bleObj)
                        clear app.bleObj;
                    end
                end
                app.isCapturing = false;
                uialert(app.UIFigure, sprintf(" Error al iniciar captura:\n%s", ME.message), "Error");
                fprintf("[BLE] Error: %s\n", ME.message);
            end
               
        end

        % Button pushed function: PreprocesarButton
        function PreprocesarButtonPushed(app, event)

            % 1) Cambiar de pestaña
            app.TabGroup.SelectedTab = app.HampelTab;
        
            % 2) Validar que hay datos en app.inputData
            if isempty(app.inputData) || ~istable(app.inputData)
                uialert(app.UIFigure,'Primero guarda o importa un CSV para tener app.inputData.','Error');
                return;
            end
        
            % 3) Detectar una columna Y típica (puedes ampliar esta lista si quieres)
            vars = app.inputData.Properties.VariableNames;
            cand = contains(vars, {'AccelerationY','Ay_g','Ay','AccY','AccelY'}, 'IgnoreCase', true);
            if ~any(cand)
                uialert(app.UIFigure,'No se encontró una columna de Y (p.ej. Ay, Ay_g, AccelerationY).','Error');
                return;
            end
            colName = vars{find(cand,1,'first')};
            y = app.inputData.(colName);
            if istable(y), y = table2array(y); end
            y = double(y(:));
            if ~isnumeric(y) || isempty(y) || all(isnan(y))
                uialert(app.UIFigure, sprintf('La columna %s no tiene datos numéricos válidos.', colName), 'Error');
                return;
            end
        
            % 4) Aplicar Hampel (UNA salida)
            W = 15;  % ventana impar recomendada
            yFilt = app.hampel_filter(y, W);
        
            % 5) Graficar en la pestaña Hampel
            ax = app.UIAxes3;
            cla(ax);
            plot(ax, y, 'DisplayName','Original'); hold(ax,'on');
            plot(ax, yFilt, 'LineWidth',1.2, 'DisplayName','Hampel');
            hold(ax,'off'); grid(ax,'on');
            title(ax, sprintf('Hampel en %s (W=%d)', colName, W), 'Interpreter','none');
            xlabel(ax,'Muestras'); ylabel(ax,'Aceleración Y (g)');
            legend(ax,'show');
        
            % 6) Guardar resultado para pasos siguientes (opcional)
            app.PreprocessedData       = yFilt;        % crea estas props si no existen
            app.PreprocessedColumnName = colName;
        
        end

        % Button pushed function: EncontrarsensorButton
        function FindSensorButtonPushed(app, event)

            try
                % Escaneo robusto (3 intentos, ordenado por RSSI)
                all = table();
                for k = 1:3
                    pause(0.3)
                    t = blelist;      % evita conflicto si existe una variable 'blelist'
                    all = [all; t];           %#ok<AGROW>
                end
                if isempty(all)
                    uialert(app.UIFigure,'No se detectaron dispositivos BLE. Reintenta.','Sin resultados');
                    return;
                end
        
                % Quitar duplicados por Address y ordenar por RSSI
                [~,idx] = unique(all.Address,'stable');
                devs = all(idx,:);
                devs = sortrows(devs,'RSSI','descend');
        
                % Filtrar candidatos típicos WT*
                keys = ["WT901","WT","WIT","JY901","WT9"];
                mask = false(height(devs),1);
                for i=1:numel(keys)
                    mask = mask | contains(upper(string(devs.Name)), upper(keys(i)));
                end
                cand = devs(mask,:);
                if isempty(cand), cand = devs; end
        
                % Construir Items (texto que ve el usuario) e ItemsData (MAC real)
                items = strcat(string(cand.Name), " (", string(cand.Address), ")  | RSSI=", string(cand.RSSI));
                data  = string(cand.Address);
        
                % Cargar al DropDown (tu control se llama "DropDown")
                app.DropDown.Items     = cellstr(items);
                app.DropDown.ItemsData = cellstr(data);
        
                % Seleccionar el primero por comodidad
                if ~isempty(data)
                    app.DropDown.Value = app.DropDown.ItemsData{1};
                    app.SelectedSensor = string(app.DropDown.Value);
                end
        
                if any(mask)
                    uialert(app.UIFigure, sprintf('Se encontraron %d WT*. Selecciona el tuyo.', sum(mask)), 'Éxito');
                else
                    uialert(app.UIFigure, 'No se detectó WT*; se muestran todos los BLE cercanos.', 'Aviso');
                end
        
            catch err
                % Mensaje útil si hay “shadowing” de blelist
                extra = "";
                if contains(err.message, 'Too many output') || contains(err.message,'Undefined')
                    extra = sprintf('\nSugerencia:\n  >> which blelist -all\n  >> clear blelist');
                end
                uialert(app.UIFigure, "Error durante la búsqueda:\n" + err.message + extra, 'Error');
            end

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)

            app.SelectedSensor = string(app.DropDown.Value);
            fprintf("Sensor seleccionado: %s\n", app.SelectedSensor);
        end

        % Button pushed function: CalibrarsensorButton
        function CalibrarsensorButtonPushed(app, event)
        
            try
                % 1) Validaciones básicas
                addr = app.SelectedSensor;
                if strlength(addr) == 0
                    uialert(app.UIFigure,'Primero selecciona un sensor BLE en el menú.','Falta selección'); 
                    return;
                end
                if app.isCapturing
                    uialert(app.UIFigure,'Detén la captura antes de calibrar.','Aviso'); 
                    return;
                end
        
                % 2) Conexión temporal
                b = ble(addr);
                pause(0.3);
        
                % 3) Obtener directamente la characteristic FFE9 del servicio FFE5
                svcUUID  = "0000FFE5-0000-1000-8000-00805F9A34FB";
                chUUID   = "0000FFE9-0000-1000-8000-00805F9A34FB";
                cWrite   = characteristic(b, svcUUID, chUUID);   % <<-- R2025a
        
                % 4) Comandos oficiales (5 bytes)
                CALIB_ACCEL = uint8([255 170 1 1 0]);   % FF AA 01 01 00
                EXIT_CAL    = uint8([255 170 1 0 0]);   % FF AA 01 00 00
                SAVE_CFG    = uint8([255 170 0 0 0]);   % FF AA 00 00 00
        
                uialert(app.UIFigure,"Coloca el sensor inmóvil y plano. Iniciando calibración del acelerómetro…","Calibrando");
        
                % 5) Escribir (algunos adaptadores requieren 'withoutresponse')
                try
                    write(cWrite, CALIB_ACCEL, "withoutresponse"); pause(3);
                    write(cWrite, EXIT_CAL,    "withoutresponse"); pause(0.3);
                    write(cWrite, SAVE_CFG,    "withoutresponse");
                catch
                    % fallback sin tercer argumento
                    write(cWrite, CALIB_ACCEL); pause(3);
                    write(cWrite, EXIT_CAL);    pause(0.3);
                    write(cWrite, SAVE_CFG);
                end
        
                % 6) Cerrar conexión temporal
                clear b  % (evita llamar delete sobre objeto ble)
        
                uialert(app.UIFigure,' Calibración del acelerómetro completada y guardada.','Éxito');
        
            catch ME
                clear b
                uialert(app.UIFigure, sprintf(" Error al calibrar acelerómetro:\n%s", ME.message), 'Error');
            end


        end

        % Button pushed function: DetenercapturaButton
        function DetenercapturaButtonPushed(app, event)
      
            try
                if ~app.isCapturing
                    uialert(app.UIFigure,"No hay captura en curso.","Aviso"); return;
                end
                app.isCapturing = false;
                try, if ~isempty(app.cNotify), unsubscribe(app.cNotify,"notification"); end, catch, end
                try, if ~isempty(app.bleObj),   clear app.bleObj;                   end, catch, end
                uialert(app.UIFigure,"Captura detenida. Ya puedes guardar y graficar.","Éxito");
            catch ME
                uialert(app.UIFigure, sprintf(" Error al detener:\n%s", ME.message), "Error");
            end
        

        end

        % Button pushed function: GuardardatosButton
        function GuardardatosButtonPushed(app, event)

            try
                nA = size(app.accBuf,1);
                nG = size(app.gyrBuf,1);
                nN = size(app.angBuf,1);
                if nA==0 && nG==0 && nN==0
                    uialert(app.UIFigure,"No hay datos para guardar.","Sin datos");
                    return;
                end
        
                % 1) Construir tabla desde buffers (misma estructura que el CSV)
                T = app.buildInputTableFromBuffers();
        
                % 2) Diálogo y guardado
                ts = datestr(now,'yyyymmdd_HHMMSS');
                [fname, pth] = uiputfile('*.csv','Guardar CSV…', "captura_"+ts+".csv");
                if isequal(fname,0), return; end
                out = fullfile(pth, fname);
                writetable(T, out);
        
                % 3) Actualizar “último archivo guardado” y preparar inputData
                app.LastSavedPath = string(out);
                app.inputData     = T;   % ← ya queda listo para Preprocesar
        
                % 4) (Opcional) Ploteo rápido post-guardado
                try
                    axA = app.UIAxes2; axG = app.UIAxes2_2;
                    cla(axA); cla(axG);
                    maskA = ~isnan(T.IdxAcc) & all(~isnan([T.Ax_g T.Ay_g T.Az_g]),2);
                    if any(maskA)
                        plot(axA, T.IdxAcc(maskA), [T.Ax_g(maskA), T.Ay_g(maskA), T.Az_g(maskA)]);
                        title(axA,'Aceleración (g)'); xlabel(axA,'Muestras'); ylabel(axA,'g');
                        legend(axA,{'Ax','Ay','Az'},'Location','best');
                    else
                        title(axA,'Aceleración (g) — sin datos válidos');
                    end
                    maskG = ~isnan(T.IdxGyr) & all(~isnan([T.Gx_dps T.Gy_dps T.Gz_dps]),2);
                    if any(maskG)
                        plot(axG, T.IdxGyr(maskG), [T.Gx_dps(maskG), T.Gy_dps(maskG), T.Gz_dps(maskG)]);
                        title(axG,'Giroscopio (°/s)'); xlabel(axG,'Muestras'); ylabel(axG,'°/s');
                        legend(axG,{'Gx','Gy','Gz'},'Location','best');
                    else
                        title(axG,'Giroscopio (°/s) — sin datos válidos');
                    end
                catch
                    % No pasa nada si falla el plot
                end
        
                uialert(app.UIFigure, "Datos guardados:\n" + string(out) + ...
                    "\n\nListos para 'Preprocesar' sin importar.", "Éxito");
        
            catch ME
                uialert(app.UIFigure, sprintf("Error al guardar:\n%s", ME.message), "Error");
            end
       
        
        end

        % Button pushed function: SiguienteButton_2
        function SiguienteButtonPushed(app, event)

        
            % 1) Ir a la pestaña Butterworth
            try
                app.TabGroup.SelectedTab = app.ButterworthTab;
            catch
                % si por algún motivo no existe, no detiene el flujo
            end
        
            % 2) Validaciones básicas
            if ~isprop(app,'PreprocessedData') || isempty(app.PreprocessedData)
                uialert(app.UIFigure, 'Primero aplica el filtro Hampel en "Preprocesar".', 'Falta Hampel');
                return;
            end
            if ~isprop(app,'PreprocessedColumnName') || isempty(app.PreprocessedColumnName)
                % Nombre de columna usado en Preprocesar (fallback si no existe)
                app.PreprocessedColumnName = 'AccelerationY'; 
            end
        
            % 3) Parámetros del filtro Butterworth
            %    Toma fs de la app si está definido, si no, usa 100 Hz.
            if isprop(app,'sampleRate_Hz') && ~isempty(app.sampleRate_Hz) && app.sampleRate_Hz>0
                fs = app.sampleRate_Hz;
            else
                fs = 100; % por defecto
            end
            cutoff = 5;      % Hz (ajústalo si lo requieres)
            order  = 4;
        
            if cutoff <= 0 || cutoff >= fs/2
                % Evitar errores si fs es muy bajo o mal definido
                cutoff = max(0.5, min( fs/2 * 0.8, 5 ));
            end
        
            % 4) Señal preprocesada (Hampel)
            yH = double(app.PreprocessedData(:));
        
            % 5) Aplicar filtro Butterworth (usa tu método de la app)
            try
                yB = app.butter_lowpass_filter(yH, cutoff, fs, order);
            catch ME
                uialert(app.UIFigure, "Error en Butterworth: "+ME.message, 'Error');
                return;
            end
        
            % 6) Graficar en la pestaña Butterworth (UIAxes5)
            ax = app.UIAxes5;
            cla(ax);
            hold(ax,'on');
        
            % Si existe la señal original en app.inputData, la graficamos para comparar
            yOriginal = [];
            if istable(app.inputData)
                vars = app.inputData.Properties.VariableNames;
                if any(strcmp(app.PreprocessedColumnName, vars))
                    yOriginal = double(app.inputData.(app.PreprocessedColumnName));
                elseif any(strcmp('Acceleration Y (g)', vars))
                    yOriginal = double(app.inputData.("Acceleration Y (g)"));
                end
            end
            if ~isempty(yOriginal)
                plot(ax, yOriginal(:), 'Color', [0.3010 0.7450 0.9330], 'DisplayName','Original');
            end
        
            plot(ax, yH, 'r', 'DisplayName','Hampel');
            plot(ax, yB, 'g', 'LineWidth', 1.2, 'DisplayName','Butterworth');
            hold(ax,'off'); grid(ax,'on'); legend(ax,'show');
            title(ax, sprintf('Filtro Hampel + Butterworth (fs=%g Hz, fc=%g Hz, orden=%d)', fs, cutoff, order), 'Interpreter','none');
            xlabel(ax,'Muestras'); ylabel(ax,'Aceleración (g)');
        
            % 7) Construir/actualizar tabla para etapas siguientes (Ventaneo, etc.)
            %    Creamos/actualizamos app.ButterFilteredData como TABLA.
            try
                % Partimos de tu inputData si es tabla; si no, creamos una tabla mínima
                if istable(app.inputData)
                    T = app.inputData;
                else
                    T = table();
                end
        
                % Asegurar que la columna de Hampel esté con el nombre detectado
                colH = app.PreprocessedColumnName;
                nT   = height(T);
        
                % Si la tabla está vacía, la creamos con el largo de la señal
                if nT == 0
                    nT = numel(yH);
                    T = table((1:nT).', yH, 'VariableNames', {'Idx','AccelerationY'});
                    colH = 'AccelerationY';  % si no había nombre, usamos éste
                else
                    % Ajustar longitudes al tamaño de la tabla
                    yHadj = yH(:);
                    if numel(yHadj) < nT, yHadj(end+1:nT,1) = NaN; end
                    if numel(yHadj) > nT, yHadj = yHadj(1:nT);   end
                    T.(colH) = yHadj;
                end
        
                % Agregar la columna filtrada Butterworth
                colB = [colH '_Butter'];
                yBadj = yB(:);
                if numel(yBadj) < nT, yBadj(end+1:nT,1) = NaN; end
                if numel(yBadj) > nT, yBadj = yBadj(1:nT);   end
                T.(colB) = yBadj;
        
                % Guardar
                app.ButterFilteredData = T;
        
            catch ME
                uialert(app.UIFigure, "No se pudo construir la tabla filtrada:\n"+ME.message, 'Error');
                return;
            end


        end

        % Button pushed function: SiguienteButton_3
        function SiguienteButton_3Pushed(app, event)
           
        % 1) Cambiar de pestaña a "Ventaneo"
        app.TabGroup.SelectedTab = app.VentaneoTab;
        
        % 2) Verificar que haya datos filtrados
        if isempty(app.ButterFilteredData)
            uialert(app.UIFigure, 'Primero aplica el filtro Butterworth.', 'Error');
            return;
        end
        
        % 3) Armar la tabla base para ventaneo
        if istable(app.ButterFilteredData)
            data_table = app.ButterFilteredData;
        else
            % Si por algún motivo quedó como vector, lo vuelvo tabla
            y = app.ButterFilteredData(:);
            data_table = table(y, 'VariableNames', {'Signal'});
        end
        
        % 4) Elegir columna preferida para graficar por defecto:
        %    usamos la misma columna de Hampel y su versión Butter si existe
        vars = data_table.Properties.VariableNames;
        colDefault = 'AccelerationY';  % fallback textual genérico
        if isprop(app,'PreprocessedColumnName') && ~isempty(app.PreprocessedColumnName)
            colH = app.PreprocessedColumnName;
            colB = [colH '_Butter'];
        else
            colH = '';
            colB = '';
        end
        
        if any(strcmp(colB, vars))
            plotCol = colB;           % prioriza Butterworth si existe
        elseif any(strcmp(colH, vars))
            plotCol = colH;           % si no, Hampel
        elseif any(contains(vars, 'Y', 'IgnoreCase', true) & contains(vars, 'Acc', 'IgnoreCase', true))
            plotCol = vars{find(contains(vars, 'Y','IgnoreCase',true) & contains(vars,'Acc','IgnoreCase',true),1,'first')};
        elseif any(strcmp('Acceleration Y (g)', vars))
            plotCol = 'Acceleration Y (g)';
        else
            plotCol = vars{1};        % última opción: primera columna
        end
        
        % 5) Definir número de ventanas
        num_windows = 5;  % ajusta a tu gusto
        n = height(data_table);
        window_size = floor(n / num_windows);
        if window_size < 1
            num_windows = 1;
        end
        
        % 6) Dividir en ventanas (toda la tabla, manteniendo columnas)
        segments = cell(1, num_windows);
        for i = 1:num_windows
            start_idx = (i - 1) * window_size + 1;
            if i == num_windows
                end_idx = n;
            else
                end_idx = i * window_size;
            end
            segments{i} = data_table(start_idx:end_idx, :);
        end
        
        % 7) Guardar estado en la app
        app.SegmentedData = segments;
        app.NumWindows    = num_windows;
        app.CurrentWindow = 1;
        
        % 8) Graficar la ventana 1
        ax = app.UIAxes6;
        cla(ax);
        y = segments{1}.(plotCol);
        plot(ax, y, 'LineWidth', 1.2);
        grid(ax, 'on');
        xlabel(ax, 'Índice dentro de la ventana');
        ylabel(ax, plotCol, 'Interpreter','none');
        title(ax, sprintf('Ventana %d de %d', app.CurrentWindow, num_windows));
        
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
           
        % --- Verificar que existan los datos segmentados ---
        if isempty(app.SegmentedData)
            uialert(app.UIFigure, 'Primero realiza el ventaneo.', 'Error');
            return;
        end
        
        % --- Aumentar contador en ciclo 1..NumWindows ---
        if isempty(app.CurrentWindow) || app.CurrentWindow < 1
            app.CurrentWindow = 1;
        else
            app.CurrentWindow = app.CurrentWindow + 1;
            if app.CurrentWindow > app.NumWindows
                app.CurrentWindow = 1;
            end
        end
        
        % --- Obtener el segmento actual ---
        segment = app.SegmentedData{app.CurrentWindow};
        if ~istable(segment)
            uialert(app.UIFigure, sprintf('Segmento %d no es una tabla válida.', app.CurrentWindow), 'Error');
            return;
        end
        
        % --- Detectar columna preferida (igual que en SiguienteButton_3Pushed) ---
        vars = segment.Properties.VariableNames;
        if isprop(app,'PreprocessedColumnName') && ~isempty(app.PreprocessedColumnName)
            colH = app.PreprocessedColumnName;
            colB = [colH '_Butter'];
        else
            colH = '';
            colB = '';
        end
        
        if any(strcmp(colB, vars))
            plotCol = colB;
        elseif any(strcmp(colH, vars))
            plotCol = colH;
        elseif any(contains(vars, 'Y','IgnoreCase',true) & contains(vars,'Acc','IgnoreCase',true))
            plotCol = vars{find(contains(vars,'Y','IgnoreCase',true) & contains(vars,'Acc','IgnoreCase',true),1,'first')};
        elseif any(strcmp('Acceleration Y (g)', vars))
            plotCol = 'Acceleration Y (g)';
        else
            plotCol = vars{1};
        end
        
        % --- Graficar ---
        ax = app.UIAxes6;
        cla(ax);
        plot(ax, segment.(plotCol), 'LineWidth', 1.2);
        grid(ax, 'on');
        xlabel(ax, 'Índice dentro de la ventana');
        ylabel(ax, plotCol, 'Interpreter','none');
        title(ax, sprintf('Ventana %d de %d', app.CurrentWindow, app.NumWindows));
       
            
        end

        % Value changed function: EjesListBox
        function EjesListBoxValueChanged(app, event)
            app.redrawSelectedAxes();
            
        end

        % Button pushed function: ExtraerCaracteristicasButton
        function ExtraerCaracteristicasButtonPushed(app, event)
       
        
            % Ir a la pestaña "Características"
            try
                app.TabGroup.SelectedTab = app.CaracteristicasTab;
            catch
            end
        
            % --- Validar ventaneo ---
            if isempty(app.SegmentedData)
                uialert(app.UIFigure, 'Primero realiza el ventaneo.', 'Error');
                return;
            end
        
            num_segments = numel(app.SegmentedData);
        
            % --- Función inline para obtener columnas candidatas por tipo (desde un SEGMENTO) ---
            %    (sin helpers ni otros métodos)
            getCols = @(vars, isAcc) ( ...
                string(vars( ...
                    ( (startsWith(string(vars),"Ax",'IgnoreCase',true) | ...
                        startsWith(string(vars),"Ay",'IgnoreCase',true) | ...
                        startsWith(string(vars),"Az",'IgnoreCase',true)) & ...
                      ( isAcc & ~contains(string(vars),"dps",'IgnoreCase',true) ) ) | ...
                    ( (startsWith(string(vars),"Gx",'IgnoreCase',true) | ...
                        startsWith(string(vars),"Gy",'IgnoreCase',true) | ...
                        startsWith(string(vars),"Gz",'IgnoreCase',true)) & ...
                      ( ~isAcc &  contains(string(vars),"dps",'IgnoreCase',true) ) ) ...
                )) );
        
            % --- Leer selección de la ListBox (Ax,Ay,Az,Gx,Gy,Gz) ---
            if isvalid(app.EjesListBox) && ~isempty(app.EjesListBox.Value)
                sel = string(app.EjesListBox.Value);
            else
                sel = ["Ax","Ay","Az","Gx","Gy","Gz"];
            end
            accSel = sel(startsWith(sel,'A','IgnoreCase',true)); % Ax,Ay,Az
            gyrSel = sel(startsWith(sel,'G','IgnoreCase',true)); % Gx,Gy,Gz
        
            % --- Recorrer segmentos y computar características inline ---
            feature_list = cell(num_segments,1);
            colname_list = {};  % se llenará al vuelo (solo una vez con el primer segmento válido)
        
            for i = 1:num_segments
                segment = app.SegmentedData{i};
        
                % Normalizar a tabla
                if istable(segment)
                    S = segment;
                elseif isnumeric(segment)
                    % Si es matriz, la nombro genéricamente (por si acaso)
                    nCols = size(segment,2);
                    vnames = compose("Signal_%d",1:nCols);
                    S = array2table(segment,'VariableNames',cellstr(vnames));
                else
                    uialert(app.UIFigure, sprintf('Segmento %d: tipo no válido.', i), 'Error');
                    feature_list{i} = NaN; %#ok<*NASGU>
                    continue;
                end
        
                vars = S.Properties.VariableNames;
        
                % Candidatas según el propio segmento
                accColsRef = getCols(vars, true);   % Ax_g, Ay_g, Az_g,...
                gyrColsRef = getCols(vars, false);  % Gx_dps, Gy_dps, Gz_dps,...
        
                % Filtrar por lo que el usuario seleccionó (match por prefijo: Ax->Ax_g, etc.)
                mapByPrefix = @(cands, shorts) string(cands( ...
                    arrayfun(@(c) any(startsWith(string(c), shorts, 'IgnoreCase',true)), string(cands)) ...
                ));
        
                cols_acc = mapByPrefix(accColsRef, accSel);
                cols_gyr = mapByPrefix(gyrColsRef, gyrSel);
        
                % Si no hay selección para alguno de los dos, simplemente no aporta columnas
                cols_to_use = [cols_acc(:); cols_gyr(:)];
        
                if isempty(cols_to_use)
                    % Si no hay ninguna columna seleccionada/presente, relleno con NaN
                    feature_list{i} = nan(1, 0);  % sin columnas → 0 features
                    continue;
                end
        
                % ---- Extracción de características por columna (inline, sin helpers) ----
                %   Por columna se calculan: Mean, Std, Var, Energy, Entropy, Skew, Kurt, MAV, ZeroCross
                %   → 9 características por señal
                feats_row = [];
                for c = 1:numel(cols_to_use)
                    cn = char(cols_to_use(c));
                    x = S.(cn);
                    if istable(x), x = table2array(x); end
                    x = double(x(:));
                    x = x(~isnan(x));
                    N = numel(x);
        
                    if N == 0
                        feats = nan(1,9);
                    else
                        mean_val  = mean(x);
                        std_val   = std(x);
                        var_val   = var(x);
                        energy_val= sum(x.^2) / N;
        
                        % Entropía por histograma (30 bins, prob)
                        [histCounts, ~] = histcounts(x, 30, 'Normalization', 'probability');
                        histCounts = histCounts(histCounts > 0);
                        entropy_val = -sum(histCounts .* log2(histCounts));
        
                        skew_val = skewness(x);
                        kurt_val = kurtosis(x);
                        mav_val  = mean(abs(x));
                        zero_cross = sum(diff(sign(x)) ~= 0);
        
                        feats = [mean_val, std_val, var_val, energy_val, entropy_val, ...
                                 skew_val, kurt_val, mav_val, zero_cross];
                    end
        
                    feats_row = [feats_row, feats]; %#ok<AGROW>
                end
        
                feature_list{i} = feats_row;
        
                % Construir nombres de columnas (una sola vez, con este segmento como referencia)
                if isempty(colname_list)
                    base_features = {'Mean','Std','Var','Energy','Entropy','Skew','Kurt','MAV','ZeroCross'};
                    cnames = cell(1, numel(cols_to_use)*numel(base_features));
                    idx = 1;
                    for c = 1:numel(cols_to_use)
                        clean_name = regexprep(char(cols_to_use(c)), '[\s\(\)]', '');
                        for b = 1:numel(base_features)
                            cnames{idx} = sprintf('%s_%s', base_features{b}, clean_name);
                            idx = idx + 1;
                        end
                    end
                    colname_list = cnames;
                end
            end
        
            % --- Empaquetar en matriz ---
            % Detectar ancho máximo (por si algún segmento no tuvo todas las columnas presentes)
            maxW = max(cellfun(@numel, feature_list));
            if isempty(maxW), maxW = 0; end
            M = nan(num_segments, maxW);
            for i = 1:num_segments
                row = feature_list{i};
                if isempty(row), continue; end
                M(i,1:numel(row)) = row;
            end
        
            % Ajustar nombres si hace falta
            if isempty(colname_list)
                colname_list = compose('Feature_%d', 1:size(M,2));
            elseif numel(colname_list) ~= size(M,2)
                % Si no cuadra, generamos genéricos para evitar error
                colname_list = compose('Feature_%d', 1:size(M,2));
            end
        
            % --- Crear tabla para mostrar ---
            T = array2table(M, ...
                'VariableNames', colname_list, ...
                'RowNames', compose('Window_%d', 1:num_segments));
        
            % --- Mostrar en la UI (sin app.UITable) ---
            % Elimina cualquier tabla previa en la pestaña y crea una nueva
            try
                oldTbl = findall(app.CaracteristicasTab, 'Type','uitable');
                if ~isempty(oldTbl), delete(oldTbl); end
            end
            uitable(app.CaracteristicasTab, ...
                    'Data', T, ...
                    'Position', [20 20 670 420], ...
                    'ColumnName', T.Properties.VariableNames, ...
                    'RowName', T.Properties.RowNames);
        
            % --- Mensaje de éxito ---
            uialert(app.UIFigure, sprintf('Características extraídas correctamente (%d ventanas × %d características).', ...
                num_segments, size(M, 2)), 'Éxito');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes7_2 = uiaxes(app.UIFigure);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CapturadedatosTab = uitab(app.TabGroup);
            app.CapturadedatosTab.Title = 'Captura de datos';
            app.UIAxes2 = uiaxes(app.CapturadedatosTab);
            title(app.UIAxes2, 'Acceleration (g)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.CapturadedatosTab);
            title(app.UIAxes2_2, 'Giroscopio (°/s)')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.IniciarcapturaButton = uibutton(app.CapturadedatosTab, 'push');
            app.IniciarcapturaButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarcapturaButtonPushed, true);
            app.IniciarcapturaButton.Text = 'Iniciar captura';
            app.PreprocesarButton = uibutton(app.CapturadedatosTab, 'push');
            app.PreprocesarButton.ButtonPushedFcn = createCallbackFcn(app, @PreprocesarButtonPushed, true);
            app.PreprocesarButton.Text = 'Preprocesar';
            app.DetenercapturaButton = uibutton(app.CapturadedatosTab, 'push');
            app.DetenercapturaButton.ButtonPushedFcn = createCallbackFcn(app, @DetenercapturaButtonPushed, true);
            app.DetenercapturaButton.Text = 'Detener captura';
            app.GuardardatosButton = uibutton(app.CapturadedatosTab, 'push');
            app.GuardardatosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardardatosButtonPushed, true);
            app.GuardardatosButton.Text = 'Guardar datos';
            app.EncontrarsensorButton = uibutton(app.CapturadedatosTab, 'push');
            app.EncontrarsensorButton.ButtonPushedFcn = createCallbackFcn(app, @FindSensorButtonPushed, true);
            app.EncontrarsensorButton.Text = 'Encontrar sensor';
            app.CalibrarsensorButton = uibutton(app.CapturadedatosTab, 'push');
            app.CalibrarsensorButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrarsensorButtonPushed, true);
            app.CalibrarsensorButton.Text = 'Calibrar sensor';
            app.DropDown = uidropdown(app.CapturadedatosTab);
            app.DropDown.Items = {'"— pulsa ''Encontrar sensor'' —"'};
            app.DropDown.ItemsData = {'""'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '""';
            app.EjesListBoxLabel = uilabel(app.CapturadedatosTab);
            app.EjesListBoxLabel.Text = 'Ejes ';
            app.EjesListBox = uilistbox(app.CapturadedatosTab);
            app.EjesListBox.Items = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.EjesListBox.ItemsData = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.EjesListBox.Multiselect = 'on';
            app.EjesListBox.ValueChangedFcn = createCallbackFcn(app, @EjesListBoxValueChanged, true);
            app.EjesListBox.Value = {'Ax', 'Ay', 'Az', 'Gx', 'Gy', 'Gz'};
            app.HampelTab = uitab(app.TabGroup);
            app.HampelTab.Title = 'Hampel';
            app.UIAxes3 = uiaxes(app.HampelTab);
            title(app.UIAxes3, 'Acceleration Y(g)')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SiguienteButton_2 = uibutton(app.HampelTab, 'push');
            app.SiguienteButton_2.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButtonPushed, true);
            app.SiguienteButton_2.Text = 'Siguiente';
            app.ButterworthTab = uitab(app.TabGroup);
            app.ButterworthTab.Title = 'Butterworth';
            app.UIAxes5 = uiaxes(app.ButterworthTab);
            title(app.UIAxes5, 'Acceleration Y(g)')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.SiguienteButton_3 = uibutton(app.ButterworthTab, 'push');
            app.SiguienteButton_3.ButtonPushedFcn = createCallbackFcn(app, @SiguienteButton_3Pushed, true);
            app.SiguienteButton_3.Text = 'Siguiente';
            app.VentaneoTab = uitab(app.TabGroup);
            app.VentaneoTab.Title = 'Ventaneo';
            app.UIAxes6 = uiaxes(app.VentaneoTab);
            title(app.UIAxes6, 'Acceleration Y(g)')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.ExtraerCaracteristicasButton = uibutton(app.VentaneoTab, 'push');
            app.ExtraerCaracteristicasButton.ButtonPushedFcn = createCallbackFcn(app, @ExtraerCaracteristicasButtonPushed, true);
            app.ExtraerCaracteristicasButton.Text = 'Extraer Caracteristicas';
            app.NextButton = uibutton(app.VentaneoTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.CaracteristicasTab = uitab(app.TabGroup);
            app.CaracteristicasTab.Title = 'Caracteristicas';
            app.Caracteristicas = uitable(app.CaracteristicasTab);
            app.Caracteristicas.ColumnName = '';
            app.Caracteristicas.RowName = {};
            app.ClasificacionTab = uitab(app.TabGroup);
            app.ClasificacionTab.Title = 'Clasificacion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end